classdef New_Controller_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        Image                           matlab.ui.control.Image
        RunningSimulationLabel          matlab.ui.control.Label
        FinalCurrentLabel               matlab.ui.control.Label
        RMSCurrentLabel                 matlab.ui.control.Label
        PeakCurrentLabel                matlab.ui.control.Label
        FinalVoltageLabel               matlab.ui.control.Label
        RMSVoltageLabel                 matlab.ui.control.Label
        PeakVoltageLabel                matlab.ui.control.Label
        SettlingTimeLabel               matlab.ui.control.Label
        PeakValueLabel                  matlab.ui.control.Label
        OvershootLabel                  matlab.ui.control.Label
        RisingTimeLabel                 matlab.ui.control.Label
        CurrentsValueTitlesLabel        matlab.ui.control.Label
        CurrentLabel                    matlab.ui.control.Label
        VoltageValueTitlesLabel         matlab.ui.control.Label
        VoltageLabel                    matlab.ui.control.Label
        PerformanceValuesTitleLabel     matlab.ui.control.Label
        SpeedPerformanceLabel           matlab.ui.control.Label
        GetStepInformationsButton       matlab.ui.control.Button
        RestoretoDefaultButton          matlab.ui.control.Button
        MaximizeCurrerntvsTimeGraphButton  matlab.ui.control.Button
        MaximizeVoltagevsTimeGraphButton  matlab.ui.control.Button
        MaximizeSpeedvsTimeGraphButton  matlab.ui.control.Button
        SimulationTimeField             matlab.ui.control.NumericEditField
        SimulationTimeLabel             matlab.ui.control.Label
        BNmsradEditField                matlab.ui.control.NumericEditField
        Jkgm2EditField                  matlab.ui.control.NumericEditField
        KVsradEditField                 matlab.ui.control.NumericEditField
        LaHEditField                    matlab.ui.control.NumericEditField
        RaOhmEditField                  matlab.ui.control.NumericEditField
        RunSimulationButton             matlab.ui.control.Button
        DesiredSpeedField               matlab.ui.control.NumericEditField
        DesiredSpeedinRPMEditFieldLabel  matlab.ui.control.Label
        BSlider                         matlab.ui.control.Slider
        ViscousFrictionininNmsradSliderLabel  matlab.ui.control.Label
        JSlider                         matlab.ui.control.Slider
        RotorInertiainkgm2SliderLabel   matlab.ui.control.Label
        MotorConstantSlider             matlab.ui.control.Slider
        MotorConstantinVsradTorqueEMFSliderLabel  matlab.ui.control.Label
        LaSlider                        matlab.ui.control.Slider
        ArmatureInductanceinHLaLabel    matlab.ui.control.Label
        RaSlider                        matlab.ui.control.Slider
        ArmatureResistanceinOhmRaSliderLabel  matlab.ui.control.Label
        DCMotorControllerLabel          matlab.ui.control.Label
        MotorParametersLabel            matlab.ui.control.Label
        UIAxesCurrent                   matlab.ui.control.UIAxes
        UIAxesVoltage                   matlab.ui.control.UIAxes
        UIAxesSpeed                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
          simOut;
          speed;
          voltage;
          current;
          speedInfo;
          voltageInfo;
          currentInfo;
    end
    
    methods (Access = private)
        
        function LabelsVisibility(app, status)
            app.FinalCurrentLabel.Visible = status;
            app.RMSCurrentLabel.Visible= status;
            app.PeakCurrentLabel.Visible = status;

            app.FinalVoltageLabel.Visible = status;
            app.PeakVoltageLabel.Visible = status;
            app.RMSVoltageLabel.Visible = status;

            app.SettlingTimeLabel.Visible = status;
            app.PeakValueLabel.Visible = status;
            app.OvershootLabel.Visible = status;
            app.RisingTimeLabel.Visible = status;

            app.CurrentLabel.Visible = status;
            app.CurrentsValueTitlesLabel.Visible = status;
            app.VoltageLabel.Visible = status;
            app.VoltageValueTitlesLabel.Visible = status;
            app.PerformanceValuesTitleLabel.Visible = status;
            app.SpeedPerformanceLabel.Visible = status;

            app.Image.Visible = status;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: RunSimulationButton
        function RunSimulationButtonPushed(app, event)
            try
                % Making the Axes Visible
                app.LabelsVisibility("off");
                app.UIAxesCurrent.Visible = "on";
                app.UIAxesCurrent.Visible = "on";
                app.UIAxesSpeed.Visible = "on";

                % Restoring Default Positions:
                app.UIAxesCurrent.Position = [700,60,660,220];
                app.UIAxesVoltage.Position = [700,279,660,220];
                app.UIAxesSpeed.Position = [700,501,660,220];
    
                % Making Plots Visible:
                app.UIAxesCurrent.Visible = "on";
                app.UIAxesCurrent.Toolbar.Visible = "on";
                app.UIAxesVoltage.Visible = "on";
                app.UIAxesVoltage.Toolbar.Visible = "on";
                app.UIAxesSpeed.Visible = "on";
                app.UIAxesSpeed.Toolbar.Visible = "on";
    
                % Collecting User Inputs:
    
                % Motors Parameters:
                Ra = app.RaOhmEditField.Value;
                La = app.LaHEditField.Value;
                K = app.KVsradEditField.Value;
                J = app.Jkgm2EditField.Value;
                B = app.BNmsradEditField.Value;
    
                % Desired Speed and Simulation Time:
                desired_speed = app.DesiredSpeedField.Value;
                simTime = app.SimulationTimeField.Value;
    
                % Assigning Variables to the Base Workspace:
                %assignin('base', "Ra", Ra);
                %assignin('base', "La", La);
                %assignin('base', "K", K);
                %assignin('base', "J", J);
                %assignin('base', "B", B);
                %assignin('base', "desired_speed", desired_speed);

                % Passing App Inputs:
                simIn = Simulink.SimulationInput('Controller_Model');
                simIn = simIn.setModelParameter('SimulationMode', 'Normal');
                %simIn = simulink.compiler.configureForDeployment(simIn);

                simIn = simIn.setVariable('Ra', Ra);
                simIn = simIn.setVariable('La', La);
                simIn = simIn.setVariable('K', K);
                simIn = simIn.setVariable('J', J);
                simIn = simIn.setVariable('B', B);
                simIn = simIn.setVariable('desired_speed', desired_speed);
                simIn = simIn.setModelParameter('StopTime', num2str(simTime));
    
                % Running Simulation:
                app.RunningSimulationLabel.Text = "Running Simulation...";
                app.RunningSimulationLabel.Visible = "on";
                drawnow;
                app.simOut = sim(simIn);
                app.RunningSimulationLabel.Text = "Simulation Completed!";

                % Extracting Output Logs:
                app.speed = app.simOut.get('speed');
                app.voltage = app.simOut.get('voltage');
                app.current = app.simOut.get('current');
    
                % Plotting the Outputs:
                plot(app.UIAxesSpeed, app.speed.Time, app.speed.Data);
                plot(app.UIAxesVoltage, app.voltage.Time, app.voltage.Data);
                plot(app.UIAxesCurrent, app.current.Time, app.current.Data);
            catch
                uialert(app.UIFigure, "Can't run the Simulation. Something went wrong. Check your Inputs.", 'Error!');
            end
        end

        % Value changing function: RaSlider
        function RaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.RaOhmEditField.Value = changingValue;
        end

        % Value changing function: LaSlider
        function LaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.LaHEditField.Value = changingValue;
        end

        % Value changing function: MotorConstantSlider
        function MotorConstantSliderValueChanging(app, event)
            changingValue = event.Value;
            app.KVsradEditField.Value = changingValue;
        end

        % Value changing function: JSlider
        function JSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Jkgm2EditField.Value = changingValue;
        end

        % Value changing function: BSlider
        function BSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BNmsradEditField.Value = changingValue;
        end

        % Value changed function: RaOhmEditField
        function RaOhmEditFieldValueChanged(app, event)
            value = app.RaOhmEditField.Value;
            if (value <= 10 && value >= 0)
                app.RaSlider.Value = value;
            else
                uialert(app.UIFigure, "Your input is out of range!", "Input Error!");
                app.RaOhmEditField.Value = app.RaSlider.Value;
            end
        end

        % Value changed function: LaHEditField
        function LaHEditFieldValueChanged(app, event)
            value = app.LaHEditField.Value;
            if (value <= 0.01 && value >= 0.0001)
                app.LaSlider.Value = value;
            else
                uialert(app.UIFigure, "Your input is out of range!", "Input Error!");
                app.LaHEditField.Value = app.LaSlider.Value;
            end
        end

        % Value changed function: KVsradEditField
        function KVsradEditFieldValueChanged(app, event)
            value = app.KVsradEditField.Value;
            if (value <= 1 && value >= 0.01)
                app.MotorConstantSlider.Value = value;
            else
                uialert(app.UIFigure, "Your input is out of range!", "Input Error!");
                app.KVsradEditField.Value = app.MotorConstantSlider.Value;
            end
        end

        % Value changed function: Jkgm2EditField
        function Jkgm2EditFieldValueChanged(app, event)
            value = app.Jkgm2EditField.Value;
            if (value <= 0.001 && value >= 1e-6)
                app.JSlider.Value = value;
            else
                uialert(app.UIFigure, "Your input is out of range!", "Input Error!");
                app.Jkgm2EditField.Value = app.JSlider.Value;
            end
        end

        % Value changed function: BNmsradEditField
        function BNmsradEditFieldValueChanged(app, event)
            value = app.BNmsradEditField.Value;
            if (value <= 0.001 && value >= 1e-6)
                app.BSlider.Value = value;
            else
                uialert(app.UIFigure, "Your input is out of range!", "Input Error!");
                app.BNmsradEditField.Value = app.BSlider.Value;
            end
        end

        % Button pushed function: MaximizeSpeedvsTimeGraphButton
        function MaximizeSpeedvsTimeGraphButtonPushed(app, event)
            try
                app.LabelsVisibility("off");
                cla(app.UIAxesCurrent);
                cla(app.UIAxesVoltage);

                app.UIAxesCurrent.Visible = "off";
                app.UIAxesCurrent.Toolbar.Visible = "off";
                app.UIAxesVoltage.Visible = "off";
                app.UIAxesVoltage.Toolbar.Visible = "off";

                app.UIAxesSpeed.Position = [700,60,660,3*220];
                app.UIAxesSpeed.Visible = "on";
                app.UIAxesSpeed.Toolbar.Visible = "on";
                plot(app.UIAxesSpeed, app.speed.Time, app.speed.Data);
            catch
                uialert(app.UIFigure, "You haven't run any simulation!", "Alert!");
            end
        end

        % Button pushed function: MaximizeVoltagevsTimeGraphButton
        function MaximizeVoltagevsTimeGraphButtonPushed(app, event)
            try
                app.LabelsVisibility("off");
                cla(app.UIAxesCurrent);
                cla(app.UIAxesSpeed);
    
                app.UIAxesCurrent.Visible = "off";
                app.UIAxesCurrent.Toolbar.Visible = "off";
                app.UIAxesSpeed.Visible = "off";
                app.UIAxesSpeed.Toolbar.Visible = "off";
    
                app.UIAxesVoltage.Position = [700,60,660,3*220];
                app.UIAxesVoltage.Visible = "on";
                app.UIAxesVoltage.Visible = "on";
                plot(app.UIAxesVoltage, app.voltage.Time, app.voltage.Data);
            catch
                uialert(app.UIFigure, "You haven't run any simulation!", "Alert!");
            end
        end

        % Button pushed function: MaximizeCurrerntvsTimeGraphButton
        function MaximizeCurrerntvsTimeGraphButtonPushed(app, event)
            try 
                app.LabelsVisibility("off");
                cla(app.UIAxesSpeed);
                cla(app.UIAxesVoltage);
    
                app.UIAxesSpeed.Visible = "off";
                app.UIAxesSpeed.Toolbar.Visible = "off";
                app.UIAxesVoltage.Visible = "off";
                app.UIAxesVoltage.Toolbar.Visible = "off";
    
                app.UIAxesCurrent.Position = [700,60,660,3*220];
                app.UIAxesCurrent.Visible = "on";
                app.UIAxesCurrent.Toolbar.Visible = "on";
                plot(app.UIAxesCurrent, app.current.Time, app.current.Data);
            catch
                uialert(app.UIFigure, "You haven't run any simulation!", "Alert!");
            end
        end

        % Button pushed function: RestoretoDefaultButton
        function RestoretoDefaultButtonPushed(app, event)
            try    
                % Clearing Labels
                app.LabelsVisibility("off");

                % Clearing Past Plots:
                cla(app.UIAxesCurrent);
                cla(app.UIAxesVoltage);
                cla(app.UIAxesSpeed);
                
                % Restoring Default Positions:
                app.UIAxesCurrent.Position = [700,60,660,220];
                app.UIAxesVoltage.Position = [700,279,660,220];
                app.UIAxesSpeed.Position = [700,501,660,220];
    
                % Making Plots Visible:
                app.UIAxesCurrent.Visible = "on";
                app.UIAxesCurrent.Toolbar.Visible = "on";
                app.UIAxesVoltage.Visible = "on";
                app.UIAxesVoltage.Toolbar.Visible = "on";
                app.UIAxesSpeed.Visible = "on";
                app.UIAxesSpeed.Toolbar.Visible = "on";
    
                % Plotting the Graphs:
                plot(app.UIAxesSpeed, app.speed.Time, app.speed.Data);
                plot(app.UIAxesVoltage, app.voltage.Time, app.voltage.Data);
                plot(app.UIAxesCurrent, app.current.Time, app.current.Data);
            catch
                uialert(app.UIFigure, "You haven't run any simulation!", "Alert!");
            end
        end

        % Button pushed function: GetStepInformationsButton
        function GetStepInformationsButtonPushed(app, event)
            try    
                % Clearing plots
                cla(app.UIAxesCurrent);
                cla(app.UIAxesVoltage);
                cla(app.UIAxesSpeed);
    
                app.UIAxesCurrent.Visible = "off";
                app.UIAxesCurrent.Toolbar.Visible = "off";
                app.UIAxesSpeed.Visible = "off";
                app.UIAxesSpeed.Toolbar.Visible = "off";
                app.UIAxesVoltage.Visible = "off";
                app.UIAxesVoltage.Toolbar.Visible = "off";

                % Extracting and Displaying Speed, Voltage and Current informations:
                app.speedInfo = stepinfo(app.speed.Data, app.speed.Time);

                app.RisingTimeLabel.Text = sprintf('%.3f s', app.speedInfo.RiseTime);
                app.OvershootLabel.Text = sprintf('%.3f %%', app.speedInfo.Overshoot);
                app.PeakValueLabel.Text = sprintf('%.3f RPM', app.speedInfo.Peak);
                app.SettlingTimeLabel.Text = sprintf('%.3f s', app.speedInfo.SettlingTime);

                app.PeakVoltageLabel.Text = sprintf('%.3f V', max(app.voltage.Data));
                app.RMSVoltageLabel.Text = sprintf('%.3f V', rms(app.voltage.Data));
                app.FinalVoltageLabel.Text = sprintf('%.3f V', mean(app.voltage.Data(end-100:end)));

                app.PeakCurrentLabel.Text = sprintf('%.3f A', max(app.current.Data));
                app.RMSCurrentLabel.Text = sprintf('%.3f A', rms(app.current.Data));
                app.FinalCurrentLabel.Text = sprintf('%.3f A', mean(app.current.Data(end-100:end)));

                % Making all the Labels Visible:
                app.LabelsVisibility("on");

            catch
                uialert(app.UIFigure, "You haven't run any simulation!", "Alert!");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxesSpeed = uiaxes(app.UIFigure);
            title(app.UIAxesSpeed, 'Speed vs Time (RPM)')
            xlabel(app.UIAxesSpeed, 'Time (s)')
            ylabel(app.UIAxesSpeed, 'Speed (RPM)')
            zlabel(app.UIAxesSpeed, 'Z')
            app.UIAxesVoltage = uiaxes(app.UIFigure);
            title(app.UIAxesVoltage, 'Voltage vs Time')
            xlabel(app.UIAxesVoltage, 'Time (s)')
            ylabel(app.UIAxesVoltage, 'Voltage (V)')
            zlabel(app.UIAxesVoltage, 'Z')
            app.UIAxesCurrent = uiaxes(app.UIFigure);
            title(app.UIAxesCurrent, 'Current vs Time')
            xlabel(app.UIAxesCurrent, 'Time (s)')
            ylabel(app.UIAxesCurrent, 'Current (A)')
            zlabel(app.UIAxesCurrent, 'Z')
            app.MotorParametersLabel = uilabel(app.UIFigure);
            app.MotorParametersLabel.Text = 'Motor Parameters:';
            app.DCMotorControllerLabel = uilabel(app.UIFigure);
            app.DCMotorControllerLabel.Text = 'DC Motor Controller';
            app.ArmatureResistanceinOhmRaSliderLabel = uilabel(app.UIFigure);
            app.ArmatureResistanceinOhmRaSliderLabel.Text = 'Armature Resistance in Ohm (Ra):';
            app.RaSlider = uislider(app.UIFigure);
            app.RaSlider.Limits = [0 10];
            app.RaSlider.ValueChangingFcn = createCallbackFcn(app, @RaSliderValueChanging, true);
            app.RaSlider.Value = 1;
            app.ArmatureInductanceinHLaLabel = uilabel(app.UIFigure);
            app.ArmatureInductanceinHLaLabel.Text = 'Armature Inductance in H (La) :';
            app.LaSlider = uislider(app.UIFigure);
            app.LaSlider.Limits = [0.0001 0.01];
            app.LaSlider.ValueChangingFcn = createCallbackFcn(app, @LaSliderValueChanging, true);
            app.LaSlider.Value = 0.0005;
            app.MotorConstantinVsradTorqueEMFSliderLabel = uilabel(app.UIFigure);
            app.MotorConstantinVsradTorqueEMFSliderLabel.Text = {'Motor Constant in Vs/rad '; '(Torque & EMF)  :'};
            app.MotorConstantSlider = uislider(app.UIFigure);
            app.MotorConstantSlider.Limits = [0.01 1];
            app.MotorConstantSlider.ValueChangingFcn = createCallbackFcn(app, @MotorConstantSliderValueChanging, true);
            app.MotorConstantSlider.Value = 0.01;
            app.RotorInertiainkgm2SliderLabel = uilabel(app.UIFigure);
            app.RotorInertiainkgm2SliderLabel.Text = 'Rotor Inertia in kg.m^2 :';
            app.JSlider = uislider(app.UIFigure);
            app.JSlider.Limits = [1e-06 0.001];
            app.JSlider.ValueChangingFcn = createCallbackFcn(app, @JSliderValueChanging, true);
            app.JSlider.Value = 0.0001;
            app.ViscousFrictionininNmsradSliderLabel = uilabel(app.UIFigure);
            app.ViscousFrictionininNmsradSliderLabel.Text = 'Viscous Friction in in Nms/rad :';
            app.BSlider = uislider(app.UIFigure);
            app.BSlider.Limits = [1e-06 0.001];
            app.BSlider.ValueChangingFcn = createCallbackFcn(app, @BSliderValueChanging, true);
            app.BSlider.Value = 1e-05;
            app.DesiredSpeedinRPMEditFieldLabel = uilabel(app.UIFigure);
            app.DesiredSpeedinRPMEditFieldLabel.Text = 'Desired Speed in RPM:';
            app.DesiredSpeedField = uieditfield(app.UIFigure, 'numeric');
            app.DesiredSpeedField.Value = 3000;
            app.RunSimulationButton = uibutton(app.UIFigure, 'push');
            app.RunSimulationButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationButtonPushed, true);
            app.RunSimulationButton.Text = 'Run Simulation';
            app.RaOhmEditField = uieditfield(app.UIFigure, 'numeric');
            app.RaOhmEditField.ValueChangedFcn = createCallbackFcn(app, @RaOhmEditFieldValueChanged, true);
            app.RaOhmEditField.Value = 1;
            app.LaHEditField = uieditfield(app.UIFigure, 'numeric');
            app.LaHEditField.ValueChangedFcn = createCallbackFcn(app, @LaHEditFieldValueChanged, true);
            app.LaHEditField.Value = 0.0005;
            app.KVsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.KVsradEditField.ValueChangedFcn = createCallbackFcn(app, @KVsradEditFieldValueChanged, true);
            app.KVsradEditField.Value = 0.01;
            app.Jkgm2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Jkgm2EditField.ValueChangedFcn = createCallbackFcn(app, @Jkgm2EditFieldValueChanged, true);
            app.Jkgm2EditField.Value = 0.0001;
            app.BNmsradEditField = uieditfield(app.UIFigure, 'numeric');
            app.BNmsradEditField.ValueChangedFcn = createCallbackFcn(app, @BNmsradEditFieldValueChanged, true);
            app.BNmsradEditField.Value = 1e-05;
            app.SimulationTimeLabel = uilabel(app.UIFigure);
            app.SimulationTimeLabel.Text = 'Simulation Time:';
            app.SimulationTimeField = uieditfield(app.UIFigure, 'numeric');
            app.SimulationTimeField.Value = 2;
            app.MaximizeSpeedvsTimeGraphButton = uibutton(app.UIFigure, 'push');
            app.MaximizeSpeedvsTimeGraphButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizeSpeedvsTimeGraphButtonPushed, true);
            app.MaximizeSpeedvsTimeGraphButton.Text = 'Maximize Speed vs Time Graph';
            app.MaximizeVoltagevsTimeGraphButton = uibutton(app.UIFigure, 'push');
            app.MaximizeVoltagevsTimeGraphButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizeVoltagevsTimeGraphButtonPushed, true);
            app.MaximizeVoltagevsTimeGraphButton.Text = 'Maximize Voltage vs Time Graph';
            app.MaximizeCurrerntvsTimeGraphButton = uibutton(app.UIFigure, 'push');
            app.MaximizeCurrerntvsTimeGraphButton.ButtonPushedFcn = createCallbackFcn(app, @MaximizeCurrerntvsTimeGraphButtonPushed, true);
            app.MaximizeCurrerntvsTimeGraphButton.Text = 'Maximize Currernt vs Time Graph';
            app.RestoretoDefaultButton = uibutton(app.UIFigure, 'push');
            app.RestoretoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @RestoretoDefaultButtonPushed, true);
            app.RestoretoDefaultButton.Text = 'Restore to Default';
            app.GetStepInformationsButton = uibutton(app.UIFigure, 'push');
            app.GetStepInformationsButton.ButtonPushedFcn = createCallbackFcn(app, @GetStepInformationsButtonPushed, true);
            app.GetStepInformationsButton.Text = 'Get Step Informations';
            app.SpeedPerformanceLabel = uilabel(app.UIFigure);
            app.SpeedPerformanceLabel.Visible = 'off';
            app.SpeedPerformanceLabel.Text = {'🌀 Speed Performance:'; ''};
            app.PerformanceValuesTitleLabel = uilabel(app.UIFigure);
            app.PerformanceValuesTitleLabel.Visible = 'off';
            app.PerformanceValuesTitleLabel.Text = {'- Rising Time    :'; '- Overshoot       :'; '- Peak Value      :'; '- Settling Time :'};
            app.VoltageLabel = uilabel(app.UIFigure);
            app.VoltageLabel.Visible = 'off';
            app.VoltageLabel.Text = '⚡ Voltage:';
            app.VoltageValueTitlesLabel = uilabel(app.UIFigure);
            app.VoltageValueTitlesLabel.Visible = 'off';
            app.VoltageValueTitlesLabel.Text = {'- Peak Voltage  :'; '- RMS Voltage  :'; '- Final Voltage :'};
            app.CurrentLabel = uilabel(app.UIFigure);
            app.CurrentLabel.Visible = 'off';
            app.CurrentLabel.Text = '🔌 Current:';
            app.CurrentsValueTitlesLabel = uilabel(app.UIFigure);
            app.CurrentsValueTitlesLabel.Visible = 'off';
            app.CurrentsValueTitlesLabel.Text = {'- Peak Current  :'; '- RMS Current  :'; '- Final Current :'};
            app.RisingTimeLabel = uilabel(app.UIFigure);
            app.RisingTimeLabel.Visible = 'off';
            app.RisingTimeLabel.Text = '___ s';
            app.OvershootLabel = uilabel(app.UIFigure);
            app.OvershootLabel.Visible = 'off';
            app.OvershootLabel.Text = '___ %';
            app.PeakValueLabel = uilabel(app.UIFigure);
            app.PeakValueLabel.Visible = 'off';
            app.PeakValueLabel.Text = '___ RPM';
            app.SettlingTimeLabel = uilabel(app.UIFigure);
            app.SettlingTimeLabel.Visible = 'off';
            app.SettlingTimeLabel.Text = '___ s';
            app.PeakVoltageLabel = uilabel(app.UIFigure);
            app.PeakVoltageLabel.Visible = 'off';
            app.PeakVoltageLabel.Text = '___ V';
            app.RMSVoltageLabel = uilabel(app.UIFigure);
            app.RMSVoltageLabel.Visible = 'off';
            app.RMSVoltageLabel.Text = '___ V';
            app.FinalVoltageLabel = uilabel(app.UIFigure);
            app.FinalVoltageLabel.Visible = 'off';
            app.FinalVoltageLabel.Text = '___ V';
            app.PeakCurrentLabel = uilabel(app.UIFigure);
            app.PeakCurrentLabel.Visible = 'off';
            app.PeakCurrentLabel.Text = '___ A';
            app.RMSCurrentLabel = uilabel(app.UIFigure);
            app.RMSCurrentLabel.Visible = 'off';
            app.RMSCurrentLabel.Text = '___ A';
            app.FinalCurrentLabel = uilabel(app.UIFigure);
            app.FinalCurrentLabel.Visible = 'off';
            app.FinalCurrentLabel.Text = '___ A';
            app.RunningSimulationLabel = uilabel(app.UIFigure);
            app.RunningSimulationLabel.Visible = 'off';
            app.RunningSimulationLabel.Text = 'Running Simulation...';
            app.Image = uiimage(app.UIFigure);
            app.Image.Visible = 'off';
            app.Image.ImageSource = 'C:\Users\Rahul Dey\OneDrive\Documents\MATLAB\P2_DC_New\Schematic.PNG';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = New_Controller_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end